classdef Cadence_Conduction_Velocity < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CONDUCTIONVELOCITYTab           matlab.ui.container.Tab
        STEP4SELECTCVENGINEPanel        matlab.ui.container.Panel
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        GRADIENTButton                  matlab.ui.control.RadioButton
        CIRCLEMETHODButton              matlab.ui.control.RadioButton
        SAVEDATAButton                  matlab.ui.control.Button
        DISPLAYLOCALCVMAPButton         matlab.ui.control.Button
        CALCULATECONDUCTIONVELOCITYButton  matlab.ui.control.Button
        DISPLAYACTIVATIONMAPButton      matlab.ui.control.Button
        STEP4SELECTMODEOFCALCULATIONPanel  matlab.ui.container.Panel
        ButtonGroup                     matlab.ui.container.ButtonGroup
        EUCLIDEANDISTANCEButton         matlab.ui.control.RadioButton
        BINSIZEDropDown                 matlab.ui.control.DropDown
        BINSIZEDropDownLabel            matlab.ui.control.Label
        BOTHButton                      matlab.ui.control.RadioButton
        AVERAGEVECTORButton             matlab.ui.control.RadioButton
        STEP3SELECTPOINTSOFINTERESTPanel  matlab.ui.container.Panel
        AutocorrectpacingsiteCheckBox   matlab.ui.control.CheckBox
        AACTIVATIONTIMEDropDownLabel_9  matlab.ui.control.Label
        COLC                            matlab.ui.control.NumericEditField
        ROWC                            matlab.ui.control.NumericEditField
        SELECTPOINTButton               matlab.ui.control.Button
        AACTIVATIONTIMEDropDownLabel_8  matlab.ui.control.Label
        AACTIVATIONTIMEDropDownLabel_7  matlab.ui.control.Label
        AACTIVATIONTIMEDropDownLabel_6  matlab.ui.control.Label
        AACTIVATIONTIMEDropDownLabel_5  matlab.ui.control.Label
        COLB                            matlab.ui.control.NumericEditField
        ROWA                            matlab.ui.control.NumericEditField
        ROWB                            matlab.ui.control.NumericEditField
        COLA                            matlab.ui.control.NumericEditField
        STEP2FIELDOFVIEWINFORMATIONPanel  matlab.ui.container.Panel
        FOV                             matlab.ui.control.NumericEditField
        AACTIVATIONTIMEDropDownLabel    matlab.ui.control.Label
        STEP2SELECTWINDOWFROMREPRESENTATIVESIGNALPanel  matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        CAM_1Tab                        matlab.ui.container.Tab
        UIAxes_cam1                     matlab.ui.control.UIAxes
        CAM_2Tab                        matlab.ui.container.Tab
        UIAxes_cam2                     matlab.ui.control.UIAxes
        CAM_3Tab                        matlab.ui.container.Tab
        UIAxes_cam3                     matlab.ui.control.UIAxes
        CAM_4Tab                        matlab.ui.container.Tab
        UIAxes_cam4                     matlab.ui.control.UIAxes
        TextArea_2                      matlab.ui.control.TextArea
        TextArea_2Label                 matlab.ui.control.Label
        STEP1SELECTFILEFORCVCALCULATIONPanel  matlab.ui.container.Panel
        LOADButton                      matlab.ui.control.Button
        ListBox_2                       matlab.ui.control.ListBox
        ListBox_2Label                  matlab.ui.control.Label
        REFRESHDIRECTORYButton          matlab.ui.control.Button
        SELECTDIRECTORYButton           matlab.ui.control.Button
    end


    properties (Access = public)
        select_input_directory_help = [];
        dir_name = [];
        mat_file_name = [];
        output_dir_name = [];

        data = [];
        x_axis = [];
        y_axis = [];
        plot_marker = [];

        mouse_coordinates = [];
        mouse_x = [];
        mouse_y = [];
        mouse_drag = [];
        active_tab = [];
        active_axes = [];
        active_data = [];
        active_image = [];
        active_camera = 1;
        active_color = 'r';
        point_A = [];
        point_B = [];
        point_C = [];
        point_counter = 1;
        pixel = [];

    end

    methods (Access = private)

        %RESET_DEFAULT_STATE  Clear data, points and UI back to defaults.
        %   reset_default_state(app)
        %   Wipes the loaded recording, the three CV points (A/B/C), the row/col
        %   fields and plot markers, clears all camera axes, and restores default
        %   FOV (17.6 mm), bin size and Average-Vector mode. Called at the start of
        %   each LOAD so a new recording starts clean.
        function reset_default_state(app)
            try

                app.data = [];
                app.pixel = [];
                app.mat_file_name = [];
                app.point_A = [];
                app.point_B = [];
                app.point_C = [];
                app.point_counter = 1;

                app.FOV.Value = 17.6;
                app.ROWA.Value = 1;
                app.COLA.Value = 1;
                app.ROWB.Value = 1;
                app.COLB.Value = 1;
                app.ROWC.Value = 1;
                app.COLC.Value = 1;

                app.AVERAGEVECTORButton.Value = true;
                app.BINSIZEDropDown.Value = '25x25';

                cla(app.UIAxes_cam1);
                cla(app.UIAxes_cam2);
                cla(app.UIAxes_cam3);
                cla(app.UIAxes_cam4);
                delete(app.plot_marker);
                app.active_camera = 1;
                app.active_color = 'r';


            catch ME
                error_message = strcat('ERROR in function reset_default_state(app) ', ME.message);
                update_console(app,error_message);
            end
        end


        %UPDATE_CONSOLE  Prepend a message to the status console.
        %   update_console(app, message)
        %   Pushes message to the top of the TextArea_2 log.
        function update_console(app, message)
            try
                app.TextArea_2.Value = [message; ''; app.TextArea_2.Value];
            catch ME
                error_message = strcat('ERROR in function update_console(app) ', ME.message);
                update_console(app,error_message);
            end
        end


        %UPDATE_ACTIVE_TAB_AXES_DATA  Point app.active_* at the selected camera tab.
        %   valid_action = update_active_tab_axes_data(app)
        %   Reads the selected CAM_1..CAM_4 tab and sets app.active_axes /
        %   active_data / active_image / active_camera accordingly. Returns 0 if the
        %   tab refers to a camera not present in the recording, else 1.
        function valid_action = update_active_tab_axes_data(app)
            try
                app.active_tab = app.TabGroup2.SelectedTab.Title;
                switch app.active_tab
                    case 'CAM_1'
                        app.active_axes = app.UIAxes_cam1;
                        app.active_data = app.data.CAM1;
                        app.active_image = app.data.CAM1_image;
                        app.active_camera = 1;
                        valid_action = 1;
                    case 'CAM_2'
                        if app.data.num_files >= 2
                            app.active_axes = app.UIAxes_cam2;
                            app.active_data = app.data.CAM2;
                            app.active_image = app.data.CAM2_image;
                            app.active_camera = 2;
                            valid_action = 1;
                        else
                            valid_action = 0; % no data loaded
                        end
                    case 'CAM_3'
                        if app.data.num_files >=3
                            app.active_axes = app.UIAxes_cam3;
                            app.active_data = app.data.CAM3;
                            app.active_image = app.data.CAM3_image;
                            app.active_camera = 3;
                            valid_action = 1;
                        else
                            valid_action = 0; % no data loaded
                        end
                    case 'CAM_4'
                        if app.data.num_files ==4
                            app.active_axes = app.UIAxes_cam4;
                            app.active_data = app.data.CAM4;
                            app.active_image = app.data.CAM4_image;
                            app.active_camera = 4;

                            valid_action = 1;
                        else
                            valid_action = 0; % no data loaded
                        end
                end
            catch ME
                error_message = strcat('ERROR in function update_active_tab_axes_data(app) ', ME.message);
                update_console(app,error_message);
            end
        end


        %DELETE_POINTS  Clear the three CV points and reset their row/col fields.
        %   delete_points(app)
        %   Resets point_A/B/C and the ROW*/COL* spinners to 1. Used before
        %   re-selecting points or after displaying a new map.
        function delete_points(app)
            try
                app.point_A = [];
                app.point_B = [];
                app.point_C = [];

                app.ROWA.Value = 1;
                app.COLA.Value = 1;
                app.ROWB.Value = 1;
                app.COLB.Value = 1;
                app.ROWC.Value = 1;
                app.COLC.Value = 1;

            catch ME
                error_message = strcat('ERROR in function delete_points(app) ', ME.message);
                update_console(app,error_message);
            end

        end


        %SELECT_POINTS  Place an interactive CV point on the active camera image.
        %   select_points(app, point_color)
        %   Draws a draggable ROI point (tagged with a unique id) on the active axes
        %   and wires ROIMoved/ObjectBeingDestroyed listeners so the row/col fields
        %   track it. Refuses to add a 4th point (A, B and C already set) or when no
        %   data is loaded.
        %   Inputs: point_color - marker colour ('r' A, 'g' B, 'b' C).
        function select_points(app, point_color)
            try
                valid_action = update_active_tab_axes_data(app);
                create_point = 1;

                if ~isempty(app.point_A) && ~isempty(app.point_B) && ~isempty(app.point_C)
                    create_point = 0;
                end
                if valid_action && create_point
                    tag_id = generate_unique_id;
                    pause(0.001);
                    point = drawpoint(app.active_axes,'Color', point_color, 'Tag', tag_id, 'MarkerSize', 5);
                    addlistener(point,'ROIMoved', @(varargin)ROIChanged(app,point)); % update if there is a change to the signal window;
                    addlistener(point,'ObjectBeingDestroyed', @(varargin)ROIDeleted(app,point)); %update if the user removes signal window;
                    update_point_list(app,point);
                    pause(0.001);
                else
                    update_console(app, 'No data loaded or points are already created. Delete old points before creating new points.')
                end

            catch ME
                error_message = strcat('ERROR in function select_points(app) ', ME.message);
                update_console(app,error_message);
            end
        end


        %ROICHANGED  Listener: refresh a point's stored row/col after it is dragged.
        %   ROIChanged(app, point)  -> calls update_point_list.
        function ROIChanged(app,point)
            try
                update_point_list(app,point);
            catch ME
                error_message = strcat('ERROR in function ROIChanged(app) ', ME.message);
                update_console(app,error_message);
            end
        end


        %ROIDELETED  Listener: clear the matching CV point when its ROI is deleted.
        %   ROIDeleted(app, point)
        %   Matches the destroyed ROI's Tag against point_A/B/C and resets that
        %   point and its row/col fields.
        function ROIDeleted(app,point)
            try
                if isvalid(app)
                    if  ~isempty(app.point_A) && strcmp(point.Tag, app.point_A.Tag)
                        app.COLA.Value = 1;
                        app.ROWA.Value = 1;
                        app.point_A = [];

                    elseif  ~isempty(app.point_B) && strcmp(point.Tag, app.point_B.Tag)
                        app.COLB.Value = 1;
                        app.ROWB.Value = 1;
                        app.point_B = [];

                    elseif ~isempty(app.point_C) && strcmp(point.Tag, app.point_C.Tag)
                        app.COLC.Value = 1;
                        app.ROWC.Value = 1;
                        app.point_C = [];

                    end
                end
            catch ME
                error_message = strcat('ERROR in function ROIDeleted(app) ', ME.message);
                update_console(app,error_message);
            end
        end


        %UPDATE_POINT_LIST  Record a point's position into the next free A/B/C slot.
        %   update_point_list(app, point)
        %   Rounds point.Position and writes it to point_A (then B, then C) and the
        %   corresponding ROW*/COL* fields, matching by Tag on update.
        function update_point_list(app, point)
            try
                position = round(point.Position);
                if isempty(app.point_A) || strcmp(point.Tag, app.point_A.Tag)
                    app.COLA.Value = position(1);
                    app.ROWA.Value = position(2);
                    app.point_A = point;

                elseif isempty(app.point_B) || strcmp(point.Tag, app.point_B.Tag)
                    app.COLB.Value = position(1);
                    app.ROWB.Value = position(2);
                    app.point_B = point;

                elseif isempty(app.point_C) || strcmp(point.Tag, app.point_C.Tag)
                    app.COLC.Value = position(1);
                    app.ROWC.Value = position(2);
                    app.point_C = point;

                end

            catch ME
                error_message = strcat('ERROR in function update_point_list(app) ', ME.message);
                update_console(app,error_message);
            end
        end


        function mode = get_cv_mode(app)
            % CV COMPUTING METHOD only (Step 4). The ENGINE (Gradient vs Circle, Step 3)
            % is handled in ensure_local_cv_field / compute_local_cv_field.
            %   1 = Euclidean, 2 = Multi-vector, 3 = Both, 0 = none selected
            try
                mode = 0;
                if app.EUCLIDEANDISTANCEButton.Value
                    mode = 1;
                elseif app.AVERAGEVECTORButton.Value
                    mode = 2;
                elseif app.BOTHButton.Value
                    mode = 3;
                end
            catch ME
                mode = 0;
                update_console(app, strcat('ERROR in function get_cv_mode(app) ', ME.message));
            end
        end


        function [R, balance, nL, nT] = wavefront_planarity(app, Vmag, Vx, Vy, fiber_axis, tol, cap)
            % R       : 1 = planar/one-way wavefront, 0 = isotropic/radial
            % balance : min/max of L vs T pool sizes (1 = both axes sampled)
            try
                valid = interior_valid_mask(app, Vmag, Vx, Vy, cap);
                a = atan2d(Vy(valid), Vx(valid));
                c = mean(cosd(2*a), 'omitnan');
                s = mean(sind(2*a), 'omitnan');
                R = hypot(c, s);
                av  = atan2d(Vy, Vx);
                ddL = abs(mod(av - fiber_axis      + 90, 180) - 90);
                ddT = abs(mod(av - (fiber_axis+90) + 90, 180) - 90);
                nL = nnz(valid & (ddL <= tol));
                nT = nnz(valid & (ddT <= tol));
                balance = min(nL, nT) / max(max(nL, nT), 1);
            catch ME
                R = 0; balance = 1; nL = 0; nT = 0;
                update_console(app, strcat('ERROR in wavefront_planarity(app) ', ME.message));
            end
        end


        %GET_ACT_AND_RESOLUTION  Activation map and pixel size for the active camera.
        %   [act_cv_map, pixel_resolution] = get_act_and_resolution(app)
        %   Returns the active camera's activation-time map (ms) from
        %   ep_metrics.act_times and the spatial resolution mm/pixel (FOV divided by
        %   pixel count). Feeds all CV engines.
        function [act_cv_map, pixel_resolution] = get_act_and_resolution(app)

            try

                act_map = app.data.ep_metrics.act_times{app.active_camera,1};
                num_pixels = size(act_map,1);
                %act_cv_map = act_map ./1000; %convert act times from ms to s
                act_cv_map = act_map;
                field_of_view = app.FOV.Value;
                pixel_resolution = (field_of_view/num_pixels); % in mm

            catch ME

                error_message = strcat('ERROR in function get_act_and_resolution(app) ', ME.message);
                update_console(app,error_message);

            end

        end


        function valid = interior_valid_mask(app, Vmag, Vx, Vy, cap)
            % Valid CV pixels for directional pooling: finite, below cap, and >=
            % EDGE_MARGIN px inside the tissue boundary (edge/curvature pixels are
            % excluded because the inverse-gradient method inflates CV there).
            try
                edge_margin = 8;
                tissue   = isfinite(Vmag);
                interior = imerode(tissue, true(2*edge_margin + 1));
                valid = interior & isfinite(Vmag) & isfinite(Vx) & isfinite(Vy) & (Vmag < cap);
            catch ME
                valid = isfinite(Vmag) & isfinite(Vx) & isfinite(Vy) & (Vmag < cap);
                update_console(app, strcat('ERROR in interior_valid_mask(app) ', ME.message));
            end
        end


        function [n_speckle, clean_map] = flag_activation_speckle(app, act_map)
            % Despeckle the activation map: isolated pixels > SPECKLE_MS from their
            % local 3x3 median are replaced by that median. Returns count + clean map.
            try
                speckle_ms = 5;
                [nr, nc] = size(act_map);
                a = double(act_map);  a(a == 0) = NaN;     % 0/NaN = off-tissue
                clean_map = act_map;
                [rr, cc] = find(isfinite(a));
                n_speckle = 0;
                for k = 1:numel(rr)
                    r = rr(k);  c = cc(k);
                    r0 = max(1,r-1); r1 = min(nr,r+1);
                    c0 = max(1,c-1); c1 = min(nc,c+1);
                    w  = a(r0:r1, c0:c1);
                    mw = median(w(isfinite(w)), 'omitnan');
                    if isfinite(mw) && abs(a(r,c) - mw) > speckle_ms
                        n_speckle = n_speckle + 1;
                        clean_map(r,c) = mw;
                    end
                end
                if n_speckle > 0
                    update_console(app, sprintf(['Activation map: %d isolated early/late ' ...
                        'speckle pixel(s) (>%d ms from local median). Using despeckled map ' ...
                        'for origin detection; two-point CV may be unreliable near them.'], ...
                        n_speckle, speckle_ms));
                end
            catch ME
                n_speckle = 0;  clean_map = act_map;
                update_console(app, strcat('ERROR in flag_activation_speckle(app) ', ME.message));
            end
        end


        function [r, c] = resolve_origin(app, act_map, ps_r, ps_c)
            % Pacing-origin policy:
            %   no manual point (ROWA/COLA == 1,1)  -> auto-detect (despeckled earliest)
            %   manual point off-tissue / NaN       -> unusable, auto (hard fallback)
            %   manual point on-tissue but suspect  (activates late OR far from earliest):
            %       "Auto-correct pacing site" checked -> replace with auto
            %       unchecked                          -> keep manual, warn only
            try
                manual   = ~(ps_r == 1 && ps_c == 1);
                [ar, ac] = detect_pacing_origin(app, act_map);      % data-driven, despeckled
                if ~manual
                    r = ar; c = ac; return;
                end
                [nr, nc] = size(act_map);
                on_tissue = ps_r>=1 && ps_r<=nr && ps_c>=1 && ps_c<=nc && ...
                    isfinite(act_map(ps_r,ps_c)) && act_map(ps_r,ps_c)~=0;
                if ~on_tissue
                    update_console(app, sprintf(['Manual pacing site (%d,%d) is off-tissue/NaN; ' ...
                        'using auto-detected origin (%d,%d).'], ps_r, ps_c, ar, ac));
                    r = ar; c = ac; return;
                end
                tmin     = min(act_map(:), [], 'omitnan');
                t_manual = act_map(ps_r, ps_c);
                d_px     = hypot(ps_r - ar, ps_c - ac);
                EARLY_MS = 5;   FAR_PX = 15;
                suspect  = (t_manual > tmin + EARLY_MS) || (d_px > FAR_PX);
                if suspect
                    if app.AutocorrectpacingsiteCheckBox.Value
                        update_console(app, sprintf(['Auto-correcting pacing site (%d,%d) ' ...
                            '[t=%.1f ms, %.0f px from earliest] -> auto origin (%d,%d).'], ...
                            ps_r, ps_c, t_manual, d_px, ar, ac));
                        r = ar; c = ac; return;
                    else
                        update_console(app, sprintf(['WARNING: manual pacing site (%d,%d) activates at ' ...
                            '%.1f ms (earliest %.1f ms) and is %.0f px from the earliest region (%d,%d). ' ...
                            'Keeping your point (tick "Auto-correct pacing site" to override).'], ...
                            ps_r, ps_c, t_manual, tmin, d_px, ar, ac));
                    end
                end
                r = ps_r; c = ps_c;          % manual stays primary
            catch ME
                r = ps_r; c = ps_c;
                update_console(app, strcat('ERROR in resolve_origin(app) ', ME.message));
            end
        end


        function [Vmag, Vx, Vy, Gx, Gy, meta] = circle_method_cv(app, T, dx, dy, r)
            % Circle Method (Siles-Paredes et al., 2022) local CV from a LAT map.
            %   T     : activation-time map (ms); NaN/0 = off-tissue
            %   dx,dy : pixel size (mm);  r : circle radius (pixels)
            % Returns CV magnitude in mm/ms (= m/s) and direction-bearing components
            % Vx,Vy — same convention/units as conduction_velocity.m, so the caller's
            % "*100" yields cm/s and atan2d(Vy,Vx) gives propagation direction.
            try
                if nargin < 5 || isempty(r), r = 10; end
                r = max(4, round(r));
                dtheta = 60;  nang = 36;  speed_max = 2.50;   % mm/ms cap (= 250 cm/s)
                ps = (dx + dy)/2;                              % mm per pixel
                [nr, nc] = size(T);
                T = double(T);  T(T == 0) = NaN;
                validT = isfinite(T);
                Tfill  = T;  Tfill(~validT) = 0;
                mask   = double(validT);
                [cols, rows] = meshgrid(1:nc, 1:nr);          % X=col, Y=row
                angles = linspace(0, 180, nang+1);  angles(end) = [];

                dLAT = nan(nr, nc, nang);               % SIGNED ΔLAT per orientation
                for k = 1:nang
                    th  = angles(k);
                    dxr = r*cosd(th);  dyr = r*sind(th);      % chord half-vector (col,row)
                    lp = interp2(cols, rows, Tfill, cols + dxr, rows + dyr, 'linear', NaN);
                    lm = interp2(cols, rows, Tfill, cols - dxr, rows - dyr, 'linear', NaN);
                    mp = interp2(cols, rows, mask,  cols + dxr, rows + dyr, 'linear', 0);
                    mm = interp2(cols, rows, mask,  cols - dxr, rows - dyr, 'linear', 0);
                    d  = lp - lm;                             % signed: + => later on the +th side
                    d(mp < 0.999 | mm < 0.999) = NaN;        % chord left the tissue
                    dLAT(:,:,k) = d;
                end
                absd = abs(dLAT);

                % propagation direction = chord with the largest |ΔLAT|
                [maxd, pdidx] = max(absd, [], 3, 'omitnan');
                th_pd = angles(pdidx);

                % resolve the 180-deg ambiguity: orient toward LATER activation
                % (early->late = away from origin), matching the gradient convention
                [iir, jjc] = ndgrid(1:nr, 1:nc);
                good_pd = isfinite(maxd);
                lin = sub2ind([nr, nc, nang], iir(good_pd), jjc(good_pd), pdidx(good_pd));
                s   = sign(dLAT(lin));                        % +1 if +th_pd is the later side
                thv = th_pd(good_pd);
                thv(s < 0) = thv(s < 0) + 180;
                th_pd(good_pd) = thv;
                th_pd(~good_pd) = NaN;

                % cosine-weighted, back-projected speed over the wedge around th_pd
                acc = zeros(nr, nc);  cnt = zeros(nr, nc);
                for k = 1:nang
                    delta  = abs(mod(angles(k) - th_pd + 90, 180) - 90);   % dist to PD
                    d      = absd(:,:,k);
                    spd    = (2*r*ps) .* cosd(delta) ./ d;                 % mm/ms
                    good   = (delta <= dtheta/2) & isfinite(spd) & (d > 1e-6);
                    acc(good) = acc(good) + spd(good);
                    cnt(good) = cnt(good) + 1;
                end
                Vmag = nan(nr, nc);
                Vmag(cnt > 0) = acc(cnt > 0) ./ cnt(cnt > 0);
                Vmag(Vmag > speed_max) = speed_max;
                Vmag(~validT) = NaN;

                Vx = Vmag .* cosd(th_pd);     % direction-bearing (axis in [0,180))
                Vy = Vmag .* sind(th_pd);
                Gx = cosd(th_pd) ./ Vmag;     % |∇T| components (ms/mm), for display
                Gy = sind(th_pd) ./ Vmag;

                meta = struct('method','circle','radius',r,'dtheta',dtheta, ...
                    'nang',nang,'dx',dx,'dy',dy,'validT',validT, ...
                    'good',isfinite(Vmag));
            catch ME
                Vmag = nan(size(T)); Vx = Vmag; Vy = Vmag; Gx = Vmag; Gy = Vmag;
                meta = struct('method','circle','error',ME.message,'good',false(size(T)));
                update_console(app, strcat('ERROR in circle_method_cv(app) ', ME.message));
            end
        end


        function cv_data = ensure_local_cv_field(app)
            % Return the active camera's local-CV field, (re)computing it if missing,
            % stale (LAT changed), or built with a different ENGINE than selected.
            % Makes the Engine choice (Gradient/Circle) drive L/T regardless of whether
            % "Display Local CV Map" was pressed first.
            desired = 'gradient';
            if isprop(app,'CIRCLEMETHODButton') && app.CIRCLEMETHODButton.Value
                desired = 'circle';
            end
            recompute = true;
            if isfield(app.data.ep_metrics, 'local_cv') && ...
                    numel(app.data.ep_metrics.local_cv) >= app.active_camera && ...
                    ~isempty(app.data.ep_metrics.local_cv{app.active_camera,1})
                cv_data = app.data.ep_metrics.local_cv{app.active_camera,1};
                [act_now, ~] = get_act_and_resolution(app);
                cached_engine = 'gradient';
                if isstruct(cv_data{1,6}) && isfield(cv_data{1,6},'method')
                    cached_engine = cv_data{1,6}.method;
                end
                if isequaln(cv_data{1,7}, act_now) && strcmp(cached_engine, desired)
                    recompute = false;
                end
            end
            if recompute
                cv_data = compute_local_cv_field(app);
                app.data.ep_metrics.local_cv{app.active_camera,1} = cv_data;
                update_console(app, sprintf('Local CV field (re)computed (engine: %s).', desired));
            end
        end


        function cv_data = compute_local_cv_field(app)
            % Build the local-CV field with the selected engine, in the cv_data layout
            % the rest of the app expects. cv_data{1,1} is CV magnitude in cm/s.
            [act_cv_map, pixel_resolution] = get_act_and_resolution(app);
            if isprop(app,'CIRCLEMETHODButton') && app.CIRCLEMETHODButton.Value
                r = get_radius(app);                          % bin-size dropdown -> radius
                [Vmag, Vx, Vy, Gx, Gy, meta] = circle_method_cv(app, act_cv_map, ...
                    pixel_resolution, pixel_resolution, r);
            else
                [Vmag, Vx, Vy, Gx, Gy, meta] = conduction_velocity(act_cv_map, ...
                    pixel_resolution, pixel_resolution, []);
            end
            cv_data{1,1} = Vmag*100;  cv_data{1,2} = Vx;  cv_data{1,3} = Vy;
            cv_data{1,4} = Gx;        cv_data{1,5} = Gy;  cv_data{1,6} = meta;
            cv_data{1,7} = act_cv_map;
            check_field_orientation(app, cv_data);
        end


        %EXTRACT_CV_EUCLIDEAN  Two-point (Euclidean) conduction-velocity estimate.
        %   extract_cv_euclidean(app)
        %   CV = distance/time between the pacing origin (point A, or auto-detected)
        %   and longitudinal/transverse points placed along/across the estimated
        %   fiber axis (or manual points B/C). Uses the local CV field for the fiber
        %   axis and warns when the wavefront is too planar for L-vs-T CV to be
        %   meaningful (wavefront_planarity). Writes results into the CV table.
        function extract_cv_euclidean(app)
            try
                act_data = app.data.ep_metrics.act_times{app.active_camera,1};
                if iscell(act_data), act_map = act_data{1,1}; else, act_map = act_data; end
                [nr, nc] = size(act_map);
                [~, px_res] = get_act_and_resolution(app);   % mm/pixel
                cap = 249.9;  kr = 1;

                cv_data = ensure_local_cv_field(app);
                Vmag = cv_data{1,1};  Vx = cv_data{1,2};  Vy = cv_data{1,3};

                % ---- origin: manual if set, else auto ----
                ps_r = app.ROWA.Value;  ps_c = app.COLA.Value;
                %if (ps_r == 1 && ps_c == 1), [ps_r, ps_c] = detect_pacing_origin(app, act_map); end
                [ps_r, ps_c] = resolve_origin(app, act_map, ps_r, ps_c);
                p_time = sample_time(app, act_map, [ps_r ps_c], [nr nc], kr);
                if isnan(p_time)
                    update_console(app, 'Origin activation is NaN; cannot compute Euclidean CV.'); return;
                end

                %fiber_axis = estimate_fiber_axis(app, Vmag, Vx, Vy, cap);
                [fiber_axis, axis_ok] = estimate_fiber_axis(app, Vmag, Vx, Vy, cap);
                update_console(app, sprintf('Euclidean CV — origin(%d,%d), fiber axis %.0f deg', ps_r, ps_c, fiber_axis));

                [planar_R, pool_balance] = wavefront_planarity(app, Vmag, Vx, Vy, fiber_axis, 30, cap);
                if planar_R > 0.5 || pool_balance < 0.3
                    update_console(app, sprintf(['CV WARNING: wavefront too uni-directional ' ...
                        '(planarity R=%.2f, pool balance=%.2f). Longitudinal vs transverse CV is ' ...
                        'not meaningful for a planar sweep — the perpendicular point sits across ' ...
                        'the wavefront (often dt<=0 or off-tissue). Trust only the along-' ...
                        'propagation value.'], planar_R, pool_balance));
                end

                map_data = nan(2,4);

                % ---- longitudinal point: manual if set, else auto along fiber axis ----
                l_r = app.ROWB.Value;  l_c = app.COLB.Value;
                have_L = ~(l_r == 1 && l_c == 1);
                if ~axis_ok && ~have_L
                    update_console(app, 'Longitudinal CV skipped: fiber axis undetermined (planar/near-isotropic wavefront).');
                else
                    if ~have_L, [l_r, l_c] = place_along_axis(app, act_map, [ps_r ps_c], fiber_axis); end
                    l_time = sample_time(app, act_map, [l_r l_c], [nr nc], kr) - p_time;
                    l_dist = pdist([ps_c, ps_r; l_c, l_r], 'euclidean') * px_res;
                    if isfinite(l_time) && l_time > 0
                        map_data(1,1:4) = [l_r, l_c, round(l_dist,1,"decimals"), round((l_dist/l_time)*100,1,"decimals")];
                    else
                        update_console(app, sprintf('Longitudinal CV skipped: dt=%.2f ms.', l_time));
                    end
                end

                % ---- transverse point: manual if set, else auto perpendicular ----
                t_r = app.ROWC.Value;  t_c = app.COLC.Value;
                if (t_r == 1 && t_c == 1), [t_r, t_c] = place_along_axis(app, act_map, [ps_r ps_c], fiber_axis + 90); end
                t_time = sample_time(app, act_map, [t_r t_c], [nr nc], kr) - p_time;
                t_dist = pdist([ps_c, ps_r; t_c, t_r], 'euclidean') * px_res;
                if isfinite(t_time) && t_time > 0
                    map_data(2,1:4) = [t_r, t_c, round(t_dist,1,"decimals"), round((t_dist/t_time)*100,1,"decimals")];
                else
                    update_console(app, sprintf('Transverse CV skipped: dt=%.2f ms.', t_time));
                end

                if any(isfinite(map_data(:)))
                    Lcv = map_data(1,4);  Tcv = map_data(2,4);
                    summary = struct();
                    if (planar_R > 0.5) || (pool_balance < 0.3) || ~axis_ok
                        domE = Tcv; if ~isfinite(domE), domE = Lcv; end
                        summary.aniso=false; summary.pattern='Unidirectional (planar)';
                        summary.detail=sprintf('Dominant CV %.1f cm/s', domE);
                        summary.text =sprintf('UNIDIRECTIONAL (planar) — dominant CV %.1f cm/s', domE);
                    else
                        summary.aniso=true; summary.pattern='Anisotropic';
                        summary.detail=sprintf('L/T = %.2f', Lcv/max(Tcv,eps));
                        summary.text =sprintf('ANISOTROPIC — L/T = %.2f (L %.1f, T %.1f cm/s)', Lcv/max(Tcv,eps), Lcv, Tcv);
                    end
                    display_feature_map(app, map_data, 'Euclidean_Distance_CV', summary);
                else
                    update_console(app, 'CV could not be calculated using Euclidean Distance Method');
                end

            catch ME
                error_message = strcat('ERROR in function extract_cv_euclidean(app) ', ME.message);
                update_console(app, error_message);
            end
        end


        function t = sample_time(app, act_map, center, gridsz, kr)
            % Median activation time over a small kernel — robust to single-pixel noise/NaN.
            try
                [nb, ~] = neighborsRadius(center, gridsz, kr);
                vals = nan(size(nb,1), 1);
                for i = 1:size(nb,1)
                    vals(i) = act_map(nb(i,1), nb(i,2));
                end
                t = median(vals, 'omitnan');
            catch ME
                t = act_map(center(1), center(2));   % fallback to the single pixel
                error_message = strcat('ERROR in function sample_time(app) ', ME.message);
                update_console(app, error_message);
            end
        end


        %GET_RADIUS  Map the Bin Size dropdown to a neighbourhood radius (pixels).
        %   radius = get_radius(app)
        %   Converts '3x3'..'NxN' to its half-width (1..), the kernel radius used by
        %   the local-CV gradient/circle fit. Defaults to 2 (5x5).
        function radius = get_radius(app)
            try
                radius = 2;
                bin = app.BINSIZEDropDown.Value;
                switch bin

                    case '3x3'
                        radius = 1;
                    case '5x5'
                        radius = 2;
                    case '7x7'
                        radius = 3;
                    case '9x9'
                        radius = 4;
                    case '11x11'
                        radius = 5;
                    case '13x13'
                        radius = 6;
                    case '15x15'
                        radius = 7;
                    case '17x17'
                        radius = 8;
                    case '19x19'
                        radius = 9;
                    case '21x21'
                        radius = 10;
                    case '23x23'
                        radius = 11;
                    case '25x25'
                        radius = 12;
                    case '27x27'
                        radius = 13;
                    case '29x29'
                        radius = 14;
                    case '31x31'
                        radius = 15;
                    case '33x33'
                        radius = 16;
                    case '35x35'
                        radius = 17;
                    case '37x37'
                        radius = 18;
                    case '39x39'
                        radius = 19;
                    case '41x41'
                        radius = 20;
                    case '43x43'
                        radius = 21;
                    case '45x45'
                        radius = 22;
                    case '47x47'
                        radius = 23;
                    case '49x49'
                        radius = 24;
                end

            catch ME
                error_message = strcat('ERROR in function get_radius(app) ', ME.message);
                update_console(app,error_message);
            end

        end


        function [r, c] = detect_pacing_origin(app, act_map)
            try
                % Pacing origin = centroid of the earliest-activated region (within 1 ms
                % of the activation minimum). Robust to ties/noise. Assumes a single
                % focal (point-paced) origin.
                [~, act_map] = flag_activation_speckle(app, act_map);
                try
                    tmin  = min(act_map(:), [], 'omitnan');
                    early = act_map <= (tmin + 1);
                    [rr, cc] = find(early);
                    r = round(mean(rr));  c = round(mean(cc));
                catch
                    [~, idx] = min(act_map(:));
                    [r, c] = ind2sub(size(act_map), idx);
                end
            catch ME
                error_message = strcat('ERROR in function detect_pacing_origin(app) ', ME.message);
                update_console(app,error_message);

            end
        end


        function [med, mn, sd, n] = directional_field_cv(app, Vmag, Vx, Vy, axis_deg, tol, cap)
            % Median/mean/std of |V| over ALL valid tissue pixels whose wavefront
            % direction is within +/- tol of axis_deg (axis-folded to 180).
            try
                %valid = isfinite(Vmag) & isfinite(Vx) & isfinite(Vy) & (Vmag < cap);
                valid = interior_valid_mask(app, Vmag, Vx, Vy, cap);
                a  = atan2d(Vy, Vx);
                dd = abs(mod(a - axis_deg + 90, 180) - 90);
                sel = valid & (dd <= tol);
                vals = Vmag(sel);
                n = numel(vals);
                if n == 0, med = NaN; mn = NaN; sd = NaN; return; end
                med = round(median(vals), 1, "decimals");
                mn  = round(mean(vals),   1, "decimals");
                sd  = round(std(vals),    1, "decimals");
            catch ME
                error_message = strcat('ERROR in function directional_field_cv(app) ', ME.message);
                update_console(app,error_message);

            end
        end


        function [r, c] = place_along_axis(app, act_map, origin, axis_deg)
            % March outward from the origin along axis_deg (both senses) and return the
            % farthest on-tissue point (finite activation) — gives the longest baseline
            % for the two-point CV while staying on valid tissue.
            try
                [nr, nc] = size(act_map);
                max_d = round(0.4 * min(nr, nc));
                best = origin;  best_d = 0;
                for s = [1 -1]
                    for dd = 1:max_d
                        rr = round(origin(1) + s*dd*sind(axis_deg));   % row = sin component
                        cc = round(origin(2) + s*dd*cosd(axis_deg));   % col = cos component
                        if rr < 1 || rr > nr || cc < 1 || cc > nc, break; end
                        if ~isfinite(act_map(rr,cc)), break; end        % left the tissue
                        if dd > best_d, best = [rr cc]; best_d = dd; end
                    end
                end
                r = best(1);  c = best(2);
            catch ME
                error_message = strcat('ERROR in function place_along_axis ', ME.message);
                update_console(app,error_message);

            end
        end


        %EXTRACT_CV_MULTI_VECTOR  Field-averaged directional conduction velocity.
        %   extract_cv_multi_vector(app)
        %   Instead of two points, pools the local CV vector field over ALL valid
        %   tissue and reports median/mean/std CV along the fiber axis (longitudinal)
        %   and perpendicular (transverse). Fiber axis is taken from manual A->B if
        %   both set, else auto-estimated; the minority/edge direction is suppressed
        %   when the axis is undetermined. Writes results into the CV table.
        function extract_cv_multi_vector(app)
            try
                cv_data = ensure_local_cv_field(app);
                Vmag = cv_data{1,1};  Vx = cv_data{1,2};  Vy = cv_data{1,3};

                act_map = app.data.ep_metrics.act_times{app.active_camera,1};
                if iscell(act_map), act_map = act_map{1,1}; end
                [nr, nc] = size(act_map);

                cap = 249.9;  ang_tol = 30;

                % ---- origin + fiber axis: manual override if provided, else auto ----
                ps_r = app.ROWA.Value;  ps_c = app.COLA.Value;
                l_r  = app.ROWB.Value;  l_c  = app.COLB.Value;
                have_ps = ~(ps_r == 1 && ps_c == 1);
                have_L  = ~(l_r  == 1 && l_c  == 1);

                if have_ps && have_L
                    fiber_axis = atan2d(l_r - ps_r, l_c - ps_c);     % manual: L defines fiber axis
                    axis_ok = true;
                    note = sprintf('manual axis %.0f deg from pacing(%d,%d)->L(%d,%d)', fiber_axis, ps_r,ps_c,l_r,l_c);
                else
                    %if ~have_ps, [ps_r, ps_c] = detect_pacing_origin(app, act_map); end
                    [ps_r, ps_c] = resolve_origin(app, act_map, ps_r, ps_c);
                    [fiber_axis, axis_ok] = estimate_fiber_axis(app, Vmag, Vx, Vy, cap);
                    note = sprintf('auto origin(%d,%d), fiber axis %.0f deg', ps_r, ps_c, fiber_axis);
                end
                update_console(app, ['Multi-vector CV — ' note]);

                % ---- directional CV over ALL valid tissue (no single-point kernel) ----
                [l_med,l_mn,l_sd,l_n] = directional_field_cv(app, Vmag, Vx, Vy, fiber_axis,      ang_tol, cap);
                [t_med,t_mn,t_sd,t_n] = directional_field_cv(app, Vmag, Vx, Vy, fiber_axis + 90, ang_tol, cap);

                if ~axis_ok
                    if l_n <= t_n          % suppress the minority/edge "longitudinal"
                        l_med = NaN; l_mn = NaN; l_sd = NaN;
                    else
                        t_med = NaN; t_mn = NaN; t_sd = NaN;
                    end
                end

                [planar_R, pool_balance] = wavefront_planarity(app, Vmag, Vx, Vy, fiber_axis, ang_tol, cap);
                if planar_R > 0.5 || pool_balance < 0.3
                    if l_n >= t_n, dom_cv = l_med; else, dom_cv = t_med; end
                    update_console(app, sprintf(['CV WARNING: wavefront too uni-directional for ' ...
                        'anisotropy (planarity R=%.2f, L/T pool balance=%.2f, n_L=%d n_T=%d). ' ...
                        'The L:T ratio below is unreliable; dominant conduction speed ~%.1f cm/s. ' ...
                        'For true anisotropy use a focal stimulus in the field of view or pace ' ...
                        'along/across fibers in separate recordings.'], ...
                        planar_R, pool_balance, l_n, t_n, dom_cv));
                else
                    update_console(app, sprintf('CV anisotropy plausible: planarity R=%.2f, pool balance=%.2f.', planar_R, pool_balance));
                end

                % ---- anisotropy verdict for the display ----
                if l_n >= t_n, dom_cv = l_med; else, dom_cv = t_med; end
                is_planar = (planar_R > 0.5) || (pool_balance < 0.3) || ~axis_ok;
                summary = struct();
                if is_planar
                    summary.aniso   = false;
                    summary.pattern = 'Unidirectional (planar)';
                    summary.detail  = sprintf('Dominant CV %.1f cm/s', dom_cv);
                    summary.text    = sprintf('UNIDIRECTIONAL (planar wavefront) — dominant CV %.1f cm/s', dom_cv);
                else
                    ratio = l_med / max(t_med, eps);
                    summary.aniso   = true;
                    summary.pattern = 'Anisotropic';
                    summary.detail  = sprintf('L/T = %.2f', ratio);
                    summary.text    = sprintf('ANISOTROPIC — L/T = %.2f (L %.1f, T %.1f cm/s)', ratio, l_med, t_med);
                end

                % representative marker points along each axis (for the display only)
                [lr, lc] = place_along_axis(app, act_map, [ps_r ps_c], fiber_axis);
                [tr, tc] = place_along_axis(app, act_map, [ps_r ps_c], fiber_axis + 90);

                map_data = [ lr, lc, l_n, l_med, l_mn, l_sd;     % longitudinal (fiber axis)
                    tr, tc, t_n, t_med, t_mn, t_sd ];   % transverse  (cross-fiber)


                display_feature_map(app, map_data, 'Average_Vector_CV', summary);

            catch ME
                error_message = strcat('ERROR in function extract_cv_multi_vector(app) ', ME.message);
                update_console(app, error_message);
            end
        end

        function [med, mn, sd, n] = directional_cv(app, Vmag, Vx, Vy, center, gridsz, radius, axis_deg, tol, cap)
            % Median/mean/std of |V| over kernel pixels whose wavefront direction is
            % within +/- tol of axis_deg (axis-folded to 180, so either sense counts).
            try
                [nb, ~] = neighborsRadius(center, gridsz, radius);
                vals = nan(size(nb,1), 1);
                for i = 1:size(nb,1)
                    r = nb(i,1);  c = nb(i,2);
                    v = Vmag(r,c);
                    if ~isfinite(v) || v >= cap, continue; end
                    a = atan2d(Vy(r,c), Vx(r,c));
                    d = abs(mod(a - axis_deg + 90, 180) - 90);   % angular distance to the axis (0..90)
                    if d <= tol
                        vals(i) = v;
                    end
                end
                vals = vals(~isnan(vals));
                n = numel(vals);
                if n == 0
                    med = NaN; mn = NaN; sd = NaN; return;
                end
                med = round(median(vals), 1, "decimals");
                mn  = round(mean(vals),   1, "decimals");
                sd  = round(std(vals),    1, "decimals");
            catch ME
                med = NaN; mn = NaN; sd = NaN; n = 0;
                error_message = strcat('ERROR in function directional_cv(app) ', ME.message);
                update_console(app, error_message);
            end
        end


        function [ax, ax_ok] = estimate_fiber_axis(app, Vmag, Vx, Vy, cap)
            % Fiber axis = interior-tissue orientation bin with the highest median
            % speed. ax_ok is FALSE when the axis is not well defined (planar / near-
            % isotropic wavefront): either weak directional anisotropy, or a second
            % distinct orientation peak rivals the first. Then the "longitudinal"
            % direction is essentially arbitrary and must not be reported.
            try
                ax = NaN;  ax_ok = false;
                valid = interior_valid_mask(app, Vmag, Vx, Vy, cap);
                a = mod(atan2d(Vy(valid), Vx(valid)), 180);     % fold to axis 0..180
                v = Vmag(valid);
                edges = 0:15:180;  centers = edges(1:end-1) + 7.5;
                medv = nan(1, numel(centers));
                for k = 1:numel(centers)
                    sel = (a >= edges(k)) & (a < edges(k+1));
                    if nnz(sel) > 20, medv(k) = median(v(sel)); end
                end
                if all(isnan(medv)), return; end

                [vmax, idx] = max(medv);
                ax = centers(idx);

                % --- stability test ---
                nb  = numel(centers);
                nbr = mod([idx-1, idx, idx+1] - 1, nb) + 1;     % winning peak + neighbours
                others = medv;  others(nbr) = NaN;
                rival  = max(others, [], 'omitnan');            % strongest competing peak
                if isnan(rival), rival = 0; end

                aniso       = vmax / min(medv, [], 'omitnan');  % fastest/slowest direction
                rival_ratio = rival / vmax;                     % ~1 => ambiguous

                ANISO_MIN = 1.30;     % need >=30% spread fastest-vs-slowest
                RIVAL_MAX = 0.90;     % competing peak must be <90% of the winner
                ax_ok = (aniso >= ANISO_MIN) && (rival_ratio <= RIVAL_MAX);

                if ~ax_ok
                    update_console(app, sprintf(['Fiber axis UNDETERMINED: planar/near-' ...
                        'isotropic wavefront (anisotropy %.2f, rival peak %.0f%% of max). ' ...
                        'Longitudinal CV skipped; report dominant conduction speed only.'], ...
                        aniso, 100*rival_ratio));
                end
            catch ME
                ax = NaN;  ax_ok = false;
                update_console(app, strcat('ERROR in estimate_fiber_axis(app) ', ME.message));
            end
        end


        %EXTRACT_CV_COMBO  Run both CV engines for comparison.
        %   extract_cv_combo(app)  -> extract_cv_euclidean then extract_cv_multi_vector.
        function extract_cv_combo(app)
            try
                extract_cv_euclidean(app);
                extract_cv_multi_vector(app);

            catch ME
                error_message = strcat('ERROR in function extract_cv_combo(app) ', ME.message);
                update_console(app,error_message);
            end
        end


        %LOAD_FILE  Load a *-metrics .mat and schema-check it at the boundary.
        %   metrics = load_file(app, file_name)
        %   Loads the 'metrics' struct from app.dir_name/file_name and validates it
        %   with schema_gate("metrics"); returns [] (logging the failures) on a
        %   schema FAIL so the app does not work on a malformed file.
        function metrics = load_file(app, file_name)
            try
                dir_path = strcat(app.dir_name, '/');
                app.mat_file_name = file_name;
                matlab_file_name = strcat(dir_path, file_name);
                fclose('all');
                file_data = load(matlab_file_name);
                metrics = file_data.metrics;
                [ok, msgs] = schema_gate(metrics, "metrics", file_name);
                for k = 1:numel(msgs), update_console(app, msgs{k}); end
                if ~ok, metrics = []; return; end

            catch ME
                error_message = strcat('ERROR in function load_file(app) ', ME.message);
                update_console(app,error_message);
            end
        end


        %DISPLAY_CV_MAP  Overlay a local-CV map on the camera background.
        %   display_cv_map(app, map, color_scale, disp_axes)
        %   Draws the grayscale camera image then the CV layer (map{1,7}, NaN =
        %   transparent) with a jet colormap/colorbar on disp_axes (active axes if
        %   empty), clipped to color_scale.
        function display_cv_map(app, map, color_scale, disp_axes)
            try

                if isempty(disp_axes)
                    disp_axes = app.active_axes;
                end

                imagesc(disp_axes, real2rgb(app.active_image,'gray'));
                hold(disp_axes, "on");
                imagesc(disp_axes, map{1,7}, 'AlphaData', ~isnan(map{1,7}));
                %set(disp_axes, 'XTick', [], 'YTick', []);
                disp_axes.XLim = [1, app.x_axis];
                disp_axes.YLim = [1, app.y_axis];
                if ~isempty(color_scale)
                    colormap(disp_axes,"jet");
                    colorbar(disp_axes, "southoutside");
                    clim(disp_axes,color_scale);
                else
                    colormap(disp_axes,"jet");
                    colorbar(disp_axes, "off");
                end

                hold(disp_axes, "on");
                Vx = map{1,2};
                Vy = map{1,3};
                T = map{1,7};
                meta = map{1,6};

                % Downsample quiver for readability
                step = max(4, round(min(size(T))/60));
                [xx, yy] = meshgrid(1:size(T,2), 1:size(T,1));
                if isstruct(meta) && isfield(meta,'good') && ~isempty(meta.good)
                    good = meta.good;
                else
                    good = isfinite(Vx) & isfinite(Vy);   % fallback if engine lacks meta.good
                end
                mask = good & mod(xx,step)==0 & mod(yy,step)==0;
                % Normalize arrows for uniform appearance
                mag = hypot(Vx, Vy);
                Vxn = Vx; Vyn = Vy;
                Vxn(mask) = Vx(mask) ./ max(mag(mask), 1e-9);
                Vyn(mask) = Vy(mask) ./ max(mag(mask), 1e-9);
                quiver(disp_axes, xx(mask), yy(mask), Vxn(mask), Vyn(mask), 0.8, 'm', 'LineWidth',0.5);


            catch ME
                error_message = strcat('ERROR in function display_cv_map(app, title, map) ', ME.message);
                update_console(app,error_message);
            end
        end


        function check_field_orientation(app, cv_data)
            % Sanity guard: local-CV vectors must point early->late (same half-plane as
            % +grad(LAT), i.e. away from the origin). The gradient engine is aligned
            % with grad(LAT) by construction, so a field that opposes grad(LAT) is also
            % >90 deg from the gradient engine -> flags a direction-sign regression
            % (e.g. the Circle-method 180-deg ambiguity).
            try
                Vx = cv_data{1,2};  Vy = cv_data{1,3};  T = cv_data{1,7};
                m  = isfinite(T) & isfinite(Vx) & isfinite(Vy);
                if nnz(m) < 50, return; end
                Tf = T;  Tf(~isfinite(Tf)) = mean(T(m), 'omitnan');
                [gx, gy] = gradient(Tf);                 % +grad(LAT): early -> late
                dotp = Vx.*gx + Vy.*gy;                  % >0 when aligned
                frac_rev = mean(dotp(m) < 0);            % fraction pointing "backward"
                if frac_rev > 0.5
                    update_console(app, sprintf(['CV DIRECTION WARNING: %.0f%% of CV vectors ' ...
                        'oppose the activation gradient (pointing toward the origin) — the ' ...
                        'engine''s direction sign may be inverted.'], 100*frac_rev));
                end
            catch ME
                update_console(app, strcat('ERROR in check_field_orientation(app) ', ME.message));
            end
        end


        %DISPLAY_MAP  Overlay a scalar map (e.g. activation times) on the active axes.
        %   display_map(app, map, color_scale)
        %   Draws the grayscale camera image then the map (NaN = transparent) with a
        %   jet colormap/colorbar clipped to color_scale.
        function display_map(app, map, color_scale)
            try

                %delete(app.active_axes.Children(:,1));
                imagesc(app.active_axes, real2rgb(app.active_image,'gray'));
                hold(app.active_axes, "on");
                imagesc(app.active_axes, map, 'AlphaData', ~isnan(map));
                set(app.active_axes, 'XTick', [], 'YTick', []);
                app.active_axes.XLim = [1, app.x_axis];
                app.active_axes.YLim = [1, app.y_axis];

                if ~isempty(color_scale)
                    colormap(app.active_axes,"jet");
                    colorbar(app.active_axes, "southoutside");
                    clim(app.active_axes,color_scale);
                else
                    colormap(app.active_axes,"jet");
                    colorbar(app.active_axes, "off");
                end

            catch ME
                error_message = strcat('ERROR in function display_map(app, title, map) ', ME.message);
                update_console(app,error_message);
            end
        end


        %DISPLAY_FEATURE_MAP  Pop-out figure of the local-CV map with the pacing point.
        %   display_feature_map(app, map_data, label, summary)
        %   Opens a labelled figure showing the active camera's local-CV map
        %   (auto-scaled to median +/- 0.5*std) with the pacing site (point A) marked.
        function display_feature_map(app, map_data, label, summary)
            try

                fig = figure("Name",label);
                ax1 = subplot(2,1,1);
                cv_data = app.data.ep_metrics.local_cv{app.active_camera,1};
                act_map = cv_data{1,7};
                median_val = median(act_map,'all', 'omitnan');
                std_val = std(act_map, 0,'all', 'omitnan');
                color_scale = [round((median_val - 0.5*std_val)), round((median_val + 0.5*std_val))];
                display_cv_map(app, cv_data, color_scale, ax1);
                hold on;

                ps_r = app.ROWA.Value;
                ps_c = app.COLA.Value;
                if ~(ps_r == 1 && ps_c == 1)
                    line(ps_c, ps_r, 'Marker','square','MarkerSize',10, 'MarkerEdgeColor','w', 'MarkerFaceColor','r');
                end

                l_r = app.ROWB.Value;
                l_c = app.COLB.Value;
                if ~(l_r == 1 && l_c == 1)
                    line(l_c, l_r, 'Marker','square','MarkerSize',10, 'MarkerEdgeColor','w', 'MarkerFaceColor','g');
                end

                t_r = app.ROWC.Value;
                t_c = app.COLC.Value;
                if ~(t_r == 1 && t_c == 1)
                    line(t_c, t_r, 'Marker','square','MarkerSize',10, 'MarkerEdgeColor','w', 'MarkerFaceColor','b');
                end

                axis square;
                ax1.Position = [0.05 0.5 0.4 0.4];

                ax2 = subplot(2,1,2);
                Vmag = cv_data{1,1};
                histogram(Vmag, 40); xlabel('Speed (cm/s)'); ylabel('Count');
                title('Conduction velocity distribution');
                ax2.Position = [0.45 0.5 0.4 0.4];

                if strcmp(label, 'Euclidean_Distance_CV')
                    row_names = {'Grid_Row'; 'Grid_Col'; 'Euclidean Distance (mm)'; 'CV (cm/s)'};

                elseif strcmp(label, 'Single_Vector_CV')
                    row_names = {'Grid_Row'; 'Grid_Col'; 'CV (cm/s)'};

                elseif strcmp(label, 'Average_Vector_CV')
                    row_names = {'Row'; 'Col'; '# of Neighbors'; 'Median CV (cm/s)'; 'Mean CV (cm/s)'; 'STD CV (cm/s)'};

                end

                if size(map_data,1) == 1
                    col_names = {'Longitudinal (B)'};
                else
                    col_names = {'Longitudinal (B)'; 'Transverse (C)'};
                end
                table_data = num2cell(map_data');     % cell so text rows can be appended

                if nargin >= 4 && isstruct(summary)
                    np = size(table_data, 2);
                    table_data = [ table_data; ...
                        [{summary.pattern}, repmat({''}, 1, np-1)]; ...
                        [{summary.detail},  repmat({''}, 1, np-1)] ];
                    row_names = [row_names; {'Pattern'}; {'Result'}];

                    % color-coded banner across the top — green = anisotropic, amber = planar
                    if summary.aniso, tcol = [0 0.5 0]; else, tcol = [1 0 0]; end
                    annotation(fig, 'textbox', [0.03 0.90 0.94 0.08], 'String', summary.text, ...
                        'FontWeight','bold', 'FontSize', 12, 'Color', tcol, ...
                        'HorizontalAlignment','center', 'VerticalAlignment','middle', ...
                        'EdgeColor','none', 'Interpreter','none', 'FitBoxToText','off');
                end

                table = uitable(fig, 'Data', table_data, 'ColumnName', col_names, ...
                    'RowName', row_names, 'Position', [700 100 600 240]);

            catch ME
                error_message = strcat('ERROR in function display_feature_map(app, data, label) ', ME.message);
                update_console(app,error_message);
            end
        end


        %GET_BG_IMAGE  Return the CAM<i> preview/background image.
        %   bgimage = get_bg_image(app, i)  -> app.data.CAM<i>_image (i = 1..4).
        function bgimage = get_bg_image(app, i)
            try
                bgimage = app.data.CAM1_image;
                if i == 2
                    bgimage = app.data.CAM2_image;
                elseif i == 3
                    bgimage = app.data.CAM3_image;
                elseif i == 4
                    bgimage = app.data.CAM4_image;
                end
            catch ME
                error_message = strcat('ERROR in function export_map(app, map_type) ', ME.message);
                update_console(app,error_message);
            end

        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %STARTUPFCN  App initialisation — add the CADENCE code tree to the path.
            %   startupFcn(app)  -> genpath-adds the current folder.
            if ~isdeployed
                cadence_bin_path = genpath(pwd);
                addpath(cadence_bin_path);
            end
           
        end

        % Button pushed function: SELECTDIRECTORYButton
        function SELECTDIRECTORYButtonPushed(app, event)
            %SELECTDIRECTORYBUTTONPUSHED  Choose the input directory and list its .mat files.
            %   Prompts with uigetdir, stores app.dir_name, and lists *.mat into ListBox_2
            %   (these are the *-metrics files from Feature Extraction).
            try
                app.UIFigure.Visible = 'off';
                app.dir_name = uigetdir;
                app.UIFigure.Visible = 'on';
                if app.dir_name ~= 0
                    search_name = [app.dir_name,'/*.mat'];
                    files = struct2cell(dir(search_name));
                    if ~isempty(files)
                        app.ListBox_2.Items = files(1,:);
                        tot_num_files = num2str(size(files,2));
                        completed_message = strcat(tot_num_files, ' files found in the selected directory. ');
                        update_console(app,completed_message);
                    else
                        warning_message = 'Did not find any *.mat files in the selected directory. ';
                        update_console(app,warning_message);
                    end
                end
            catch ME
                error_message = strcat('ERROR - FEATURE EXTRACTION - SELECTINPUTDIRECTORYButtonPushed(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end

        % Button pushed function: REFRESHDIRECTORYButton
        function REFRESHDIRECTORYButtonPushed(app, event)
            %REFRESHDIRECTORYBUTTONPUSHED  Re-scan the current directory for .mat files.
            %   Re-lists *.mat into ListBox_2 without re-prompting.
            try
                if app.dir_name ~= 0
                    search_name = [app.dir_name,'/*.mat'];
                    files = struct2cell(dir(search_name));
                    if ~isempty(files)
                        app.ListBox_2.Items = files(1,:);
                        completed_message = 'Refreshed selected directory.  ';
                        update_console(app,completed_message);
                    else
                        warning_message = 'Did not find any *.mat files in the selected directory after refresh.  ';
                        update_console(app,warning_message);
                    end
                end

            catch ME
                error_message = strcat('ERROR - REFRESHINPUTDIRECTORYButtonPushed(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)
            %LOADBUTTONPUSHED  Load the selected recording and draw its camera images.
            %   Resets app state, loads the metrics struct (load_file), then renders each
            %   present camera's background image into its tab axes and sizes the axes to
            %   the image. Progress shows in a dialog and the console.
            try

                d = uiprogressdlg(app.UIFigure, 'Title', 'Loading data file', 'Indeterminate','on');
                reset_default_state(app);
                file_name = app.ListBox_2.Value;
                if ~isempty(file_name)

                    d.Message = strcat('Loading data file ...', file_name);

                    app.data = load_file(app,file_name);

                    [app.x_axis, app.y_axis] = size(app.data.CAM1(:,:,1));
                    app.UIAxes_cam1.XLim = [1, app.x_axis];
                    app.UIAxes_cam1.YLim = [1, app.y_axis];
                    imagesc(app.UIAxes_cam1, real2rgb(app.data.CAM1_image,'gray'));
                    set(app.UIAxes_cam1, 'XTick', [], 'YTick', []);
                    colorbar(app.UIAxes_cam1, "off");

                    if app.data.num_files >=2
                        app.UIAxes_cam2.XLim = [1, app.x_axis];
                        app.UIAxes_cam2.YLim = [1, app.y_axis];
                        imagesc(app.UIAxes_cam2, real2rgb(app.data.CAM2_image,'gray'));
                        set(app.UIAxes_cam2, 'XTick', [], 'YTick', []);
                        colorbar(app.UIAxes_cam2, "off");
                    end

                    if app.data.num_files >=3
                        app.UIAxes_cam3.XLim = [1, app.x_axis];
                        app.UIAxes_cam3.YLim = [1, app.y_axis];
                        imagesc(app.UIAxes_cam3, real2rgb(app.data.CAM3_image,'gray'));
                        set(app.UIAxes_cam3, 'XTick', [], 'YTick', []);
                        colorbar(app.UIAxes_cam3, "off");
                    end

                    if app.data.num_files ==4
                        app.UIAxes_cam4.XLim = [1, app.x_axis];
                        app.UIAxes_cam4.YLim = [1, app.y_axis];
                        imagesc(app.UIAxes_cam4, real2rgb(app.data.CAM4_image,'gray'));
                        set(app.UIAxes_cam4, 'XTick', [], 'YTick', []);
                        colorbar(app.UIAxes_cam4, "off");
                    end

                    close(d);
                    completed_message = strcat(file_name, ' ....loaded successfully ');
                    update_console(app,completed_message);

                end


            catch ME
                error_message = strcat('ERROR - FEATUREEXTRACTION- LOADButtonPushed(app, event) ', ME.message);
                update_console(app,error_message);
                close(d);
            end

        end

        % Button pushed function: CALCULATECONDUCTIONVELOCITYButton
        function CALCULATECONDUCTIONVELOCITYButtonPushed(app, event)
            %CALCULATECONDUCTIONVELOCITYBUTTONPUSHED  Run the chosen CV engine.
            %   Dispatches on the selected CV method (get_cv_mode): Euclidean,
            %   Multi-vector, or Both; warns if no method/data is selected.
            try
                if ~isempty(app.data)
                    cv_mode = get_cv_mode(app);
                    switch cv_mode
                        case 1
                            extract_cv_euclidean(app);
                        case 2
                            extract_cv_multi_vector(app);
                        case 3
                            extract_cv_combo(app);
                        otherwise
                            update_console(app, 'Select a CV computing method: Euclidean, Multi-vector, or Both.');
                    end

                else
                    message = strcat("Data not loaded");
                    update_console(app, message);
                end
            catch ME
                error_message = strcat('ERROR - FEATUREEXTRACTION- CALCULATECONDUCTIONVELOCITYButtonPushed(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end

        % Button pushed function: SELECTPOINTButton
        function SELECTPOINTButtonPushed(app, event)
            %SELECTPOINTBUTTONPUSHED  Add the next CV point (A->B->C, red/green/blue).
            %   Picks the colour for the next unset point and calls select_points.
            try
                if ~isempty(app.data)
                    point_color = 'r';
                    if isempty(app.point_A)
                        point_color = 'r';
                    elseif isempty(app.point_B)
                        point_color = 'g';
                    elseif isempty(app.point_C)
                        point_color = 'b';
                    end
                    select_points(app, point_color);
                else
                    message = strcat("Data not loaded");
                    update_console(app, message);
                end
            catch ME
                error_message = strcat('ERROR - CONDUCTIONVELOCITY- SELECTPOINTButtonPushed(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end

        % Button pushed function: DISPLAYACTIVATIONMAPButton
        function DISPLAYACTIVATIONMAPButtonPushed(app, event)
            %DISPLAYACTIVATIONMAPBUTTONPUSHED  Show the activation-time map for the active camera.
            %   Renders ep_metrics.act_times for the active camera (auto-scaled to
            %   median +/- 2*std) and clears any existing points so the user can pick new
            %   pacing/measurement sites.
            try

                if ~isempty(app.data) && isfield(app.data.ep_metrics,'act_times')
                    update_active_tab_axes_data(app);
                    if app.active_camera <= size(app.data.ep_metrics.act_times,1)
                        map = app.data.ep_metrics.act_times{app.active_camera,1};
                        median_val = median(map,'all', 'omitnan');
                        std_val = std(map, 0,'all', 'omitnan');
                        color_scale = [round((median_val - 2*std_val)), round((median_val + 2*std_val))];
                        display_map(app,map,color_scale);
                        delete_points(app);

                        message = "Activation Times Data Map executed successfully.. Please select new points for calculating CV.";
                        update_console(app, message);
                    else
                        message = "Activation Times Data not available";
                        update_console(app, message);

                    end
                else
                    message = "Data not loaded or not available";
                    update_console(app, message);
                end

            catch ME

                error_message = strcat('ERROR - CONDUCTIONVELOCITY- DISPLAYACTIVATIONMAP(app, event) ', ME.message);
                update_console(app,error_message);
            end

        end

        % Button pushed function: DISPLAYLOCALCVMAPButton
        function DISPLAYLOCALCVMAPButtonPushed(app, event)
            %DISPLAYLOCALCVMAPBUTTONPUSHED  Compute and show the local-CV map.
            %   Builds the local CV vector field (ensure_local_cv_field), stores it in
            %   ep_metrics.local_cv, and overlays it (auto-scaled to median +/- 2*std),
            %   then clears existing points.
            try
                if ~isempty(app.data)
                    if isfield(app.data.ep_metrics,'act_times')
                        update_active_tab_axes_data(app);

                        cv_data = ensure_local_cv_field(app);
                        act_map = cv_data{1,7};

                        if ~isempty(cv_data)
                            app.data.ep_metrics.local_cv{app.active_camera,1} = cv_data;
                            median_val = median(act_map,'all', 'omitnan');
                            std_val    = std(act_map, 0,'all', 'omitnan');
                            color_scale = [round((median_val - 2*std_val)), round((median_val + 2*std_val))];

                            display_cv_map(app, cv_data, color_scale, []);
                            delete_points(app);
                            message = "Local CV Map executed successfully.. Please select new points for calculating CV.";
                            update_console(app, message);
                        else
                            message = "Local CV data could not be computed";
                            update_console(app, message);
                        end
                    else
                        message = "Activation Times data not available";
                        update_console(app, message);
                    end
                else
                    message = "Data not loaded";
                    update_console(app, message);
                end

            catch ME
                error_message = strcat('ERROR - CONDUCTIONVELOCITY- DISPLAYLOCALCVMAP(app, event) ', ME.message);
                update_console(app,error_message);
            end

        end

        % Button pushed function: SAVEDATAButton
        function SAVEDATAButtonPushed(app, event)
            %SAVEDATABUTTONPUSHED  Save the recording (with CV results) back to disk.
            %   Writes app.data as the 'metrics' struct to app.dir_name/mat_file_name
            %   (-v7.3), persisting the computed local CV / CV table.
            try
                if ~isempty(app.data)
                    d = uiprogressdlg(app.UIFigure, 'Title', 'Conduction Velocity', 'Indeterminate','on');
                    d.Message = 'Saving the data file';
                    dir_path = app.dir_name;

                    file_path = strcat(dir_path, '/', app.mat_file_name);
                    metrics = app.data;

                    save(file_path,"metrics", '-v7.3');

                    d.Message = 'Saved the data file.. sucessfully';
                    update_console(app, d.Message);

                else
                    message = strcat("Data not loaded");
                    update_console(app,message);
                end
            catch ME
                error_message = strcat('ERROR - Save Data(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Cadence - Cardiac Signal Processing and Analysis Software';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CONDUCTIONVELOCITYTab = uitab(app.TabGroup);
            app.CONDUCTIONVELOCITYTab.Title = 'CONDUCTION VELOCITY';
            app.STEP1SELECTFILEFORCVCALCULATIONPanel = uipanel(app.CONDUCTIONVELOCITYTab);
            app.STEP1SELECTFILEFORCVCALCULATIONPanel.Title = 'STEP 1: SELECT FILE FOR CV CALCULATION';
            app.SELECTDIRECTORYButton = uibutton(app.STEP1SELECTFILEFORCVCALCULATIONPanel, 'push');
            app.SELECTDIRECTORYButton.ButtonPushedFcn = createCallbackFcn(app, @SELECTDIRECTORYButtonPushed, true);
            app.SELECTDIRECTORYButton.Text = {'SELECT DIRECTORY'; ''};
            app.REFRESHDIRECTORYButton = uibutton(app.STEP1SELECTFILEFORCVCALCULATIONPanel, 'push');
            app.REFRESHDIRECTORYButton.ButtonPushedFcn = createCallbackFcn(app, @REFRESHDIRECTORYButtonPushed, true);
            app.REFRESHDIRECTORYButton.Text = {'REFRESH DIRECTORY'; ''};
            app.ListBox_2Label = uilabel(app.STEP1SELECTFILEFORCVCALCULATIONPanel);
            app.ListBox_2Label.Text = 'List Box';
            app.ListBox_2 = uilistbox(app.STEP1SELECTFILEFORCVCALCULATIONPanel);
            app.ListBox_2.Items = {};
            app.ListBox_2.Value = {};
            app.LOADButton = uibutton(app.STEP1SELECTFILEFORCVCALCULATIONPanel, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Text = 'LOAD';
            app.TextArea_2Label = uilabel(app.CONDUCTIONVELOCITYTab);
            app.TextArea_2Label.Text = 'Text Area';
            app.TextArea_2 = uitextarea(app.CONDUCTIONVELOCITYTab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Placeholder = ' ';
            app.TextArea_2.Value = {'Welcome to CADENCE 2026!! '; ''; 'STEP 1) Please select the appropriate input directory containing the Matlab data files (*.mat). If you haven''t already, please use the Signal Conditioning Module to process your data files before extracting features. Next, load a data file for feature extraction. '; ''; 'STEP 2) Enter the field of view information. Pixel resolution is calculated by dividing total number of pixels by camera sensor size. For e.g., SCIMedia N256 cameras has 256 x 256 pixels and the sensor size is 17.6mm x 17.6mm. Therefore the pixel resolution for that camera would be 17.6/256 = 0.07mm. Moreover, without any magnification for this camera, the field of view would be 17.6mm.  '; ''; 'STEP 3) Select the points of interest. Typically you select the pacing site (Point A)  and the location at which the CV needs to be calculated (Point B). Typically CV can be measured accurately within 20 mm of the pacing site. In addition, the pixel resolution of at least 1mm is needed.'; ''; 'STEP 4) Select the mode of CV calculation.  Euclidean Distance Method, Single Vector Method, Average Vector Method, or All of the Above(for comparison).'; ''; 'STEP 5) Click on the Calculate Conduction Velocity Button to compute CV values. '; ''};
            app.STEP2SELECTWINDOWFROMREPRESENTATIVESIGNALPanel = uipanel(app.CONDUCTIONVELOCITYTab);
            app.STEP2SELECTWINDOWFROMREPRESENTATIVESIGNALPanel.Title = 'ACTIVATION MAPS FOR CALCULATING CONDUCTION VELOCITY';
            app.TabGroup2 = uitabgroup(app.STEP2SELECTWINDOWFROMREPRESENTATIVESIGNALPanel);
            app.CAM_1Tab = uitab(app.TabGroup2);
            app.CAM_1Tab.Title = 'CAM_1';
            app.UIAxes_cam1 = uiaxes(app.CAM_1Tab);
            app.UIAxes_cam1.XLim = [1 100];
            app.UIAxes_cam1.YLim = [1 100];
            app.UIAxes_cam1.YDir = 'reverse';
            app.UIAxes_cam1.XTick = [];
            app.UIAxes_cam1.XTickLabel = '';
            app.UIAxes_cam1.YTick = [];
            app.UIAxes_cam1.YTickLabel = '';
            app.UIAxes_cam1.ZTick = [];
            app.CAM_2Tab = uitab(app.TabGroup2);
            app.CAM_2Tab.Title = 'CAM_2';
            app.UIAxes_cam2 = uiaxes(app.CAM_2Tab);
            app.UIAxes_cam2.XLim = [1 100];
            app.UIAxes_cam2.YLim = [1 100];
            app.UIAxes_cam2.YDir = 'reverse';
            app.UIAxes_cam2.XTick = [];
            app.UIAxes_cam2.XTickLabel = '';
            app.UIAxes_cam2.YTick = [];
            app.UIAxes_cam2.YTickLabel = '';
            app.UIAxes_cam2.ZTick = [];
            app.CAM_3Tab = uitab(app.TabGroup2);
            app.CAM_3Tab.Title = 'CAM_3';
            app.UIAxes_cam3 = uiaxes(app.CAM_3Tab);
            app.UIAxes_cam3.XLim = [1 100];
            app.UIAxes_cam3.YLim = [1 100];
            app.UIAxes_cam3.YDir = 'reverse';
            app.UIAxes_cam3.XTick = [];
            app.UIAxes_cam3.XTickLabel = '';
            app.UIAxes_cam3.YTick = [];
            app.UIAxes_cam3.YTickLabel = '';
            app.UIAxes_cam3.ZTick = [];
            app.CAM_4Tab = uitab(app.TabGroup2);
            app.CAM_4Tab.Title = 'CAM_4';
            app.UIAxes_cam4 = uiaxes(app.CAM_4Tab);
            app.UIAxes_cam4.XLim = [1 100];
            app.UIAxes_cam4.YLim = [1 100];
            app.UIAxes_cam4.YDir = 'reverse';
            app.UIAxes_cam4.XTick = [];
            app.UIAxes_cam4.XTickLabel = '';
            app.UIAxes_cam4.YTick = [];
            app.UIAxes_cam4.YTickLabel = '';
            app.UIAxes_cam4.ZTick = [];
            app.STEP2FIELDOFVIEWINFORMATIONPanel = uipanel(app.CONDUCTIONVELOCITYTab);
            app.STEP2FIELDOFVIEWINFORMATIONPanel.Title = 'STEP 2: FIELD OF VIEW INFORMATION';
            app.AACTIVATIONTIMEDropDownLabel = uilabel(app.STEP2FIELDOFVIEWINFORMATIONPanel);
            app.AACTIVATIONTIMEDropDownLabel.Text = 'A) FIELD OF VIEW (MM)';
            app.FOV = uieditfield(app.STEP2FIELDOFVIEWINFORMATIONPanel, 'numeric');
            app.FOV.Limits = [1 Inf];
            app.FOV.Value = 20;
            app.STEP3SELECTPOINTSOFINTERESTPanel = uipanel(app.CONDUCTIONVELOCITYTab);
            app.STEP3SELECTPOINTSOFINTERESTPanel.Title = 'STEP 3: SELECT POINTS OF INTEREST';
            app.COLA = uieditfield(app.STEP3SELECTPOINTSOFINTERESTPanel, 'numeric');
            app.COLA.Limits = [1 Inf];
            app.COLA.Value = 1;
            app.ROWB = uieditfield(app.STEP3SELECTPOINTSOFINTERESTPanel, 'numeric');
            app.ROWB.Limits = [1 Inf];
            app.ROWB.Value = 1;
            app.ROWA = uieditfield(app.STEP3SELECTPOINTSOFINTERESTPanel, 'numeric');
            app.ROWA.Limits = [1 Inf];
            app.ROWA.Value = 1;
            app.COLB = uieditfield(app.STEP3SELECTPOINTSOFINTERESTPanel, 'numeric');
            app.COLB.Limits = [1 Inf];
            app.COLB.Value = 1;
            app.AACTIVATIONTIMEDropDownLabel_5 = uilabel(app.STEP3SELECTPOINTSOFINTERESTPanel);
            app.AACTIVATIONTIMEDropDownLabel_5.Text = 'POINT A (PACING SITE)';
            app.AACTIVATIONTIMEDropDownLabel_6 = uilabel(app.STEP3SELECTPOINTSOFINTERESTPanel);
            app.AACTIVATIONTIMEDropDownLabel_6.Text = 'POINT B (LONGITUDINAL)';
            app.AACTIVATIONTIMEDropDownLabel_7 = uilabel(app.STEP3SELECTPOINTSOFINTERESTPanel);
            app.AACTIVATIONTIMEDropDownLabel_7.Text = 'ROW';
            app.AACTIVATIONTIMEDropDownLabel_8 = uilabel(app.STEP3SELECTPOINTSOFINTERESTPanel);
            app.AACTIVATIONTIMEDropDownLabel_8.Text = 'COLUMN';
            app.SELECTPOINTButton = uibutton(app.STEP3SELECTPOINTSOFINTERESTPanel, 'push');
            app.SELECTPOINTButton.ButtonPushedFcn = createCallbackFcn(app, @SELECTPOINTButtonPushed, true);
            app.SELECTPOINTButton.Text = 'SELECT POINT';
            app.ROWC = uieditfield(app.STEP3SELECTPOINTSOFINTERESTPanel, 'numeric');
            app.ROWC.Limits = [1 Inf];
            app.ROWC.Value = 1;
            app.COLC = uieditfield(app.STEP3SELECTPOINTSOFINTERESTPanel, 'numeric');
            app.COLC.Limits = [1 Inf];
            app.COLC.Value = 1;
            app.AACTIVATIONTIMEDropDownLabel_9 = uilabel(app.STEP3SELECTPOINTSOFINTERESTPanel);
            app.AACTIVATIONTIMEDropDownLabel_9.Text = 'POINT C (TRANSVERSE)';
            app.AutocorrectpacingsiteCheckBox = uicheckbox(app.STEP3SELECTPOINTSOFINTERESTPanel);
            app.AutocorrectpacingsiteCheckBox.Text = 'Auto-correct pacing site';
            app.STEP4SELECTMODEOFCALCULATIONPanel = uipanel(app.CONDUCTIONVELOCITYTab);
            app.STEP4SELECTMODEOFCALCULATIONPanel.Title = 'STEP 4: SELECT MODE OF CALCULATION';
            app.ButtonGroup = uibuttongroup(app.STEP4SELECTMODEOFCALCULATIONPanel);
            app.AVERAGEVECTORButton = uiradiobutton(app.ButtonGroup);
            app.AVERAGEVECTORButton.Text = 'AVERAGE VECTOR';
            app.AVERAGEVECTORButton.Value = true;
            app.BOTHButton = uiradiobutton(app.ButtonGroup);
            app.BOTHButton.Text = 'BOTH';
            app.BINSIZEDropDownLabel = uilabel(app.ButtonGroup);
            app.BINSIZEDropDownLabel.Text = 'BIN SIZE';
            app.BINSIZEDropDown = uidropdown(app.ButtonGroup);
            app.BINSIZEDropDown.Items = {'5x5', '15x15', '25x25', '35x35', '45x45'};
            app.BINSIZEDropDown.Value = '25x25';
            app.EUCLIDEANDISTANCEButton = uiradiobutton(app.ButtonGroup);
            app.EUCLIDEANDISTANCEButton.Text = 'EUCLIDEAN DISTANCE';
            app.DISPLAYACTIVATIONMAPButton = uibutton(app.CONDUCTIONVELOCITYTab, 'push');
            app.DISPLAYACTIVATIONMAPButton.ButtonPushedFcn = createCallbackFcn(app, @DISPLAYACTIVATIONMAPButtonPushed, true);
            app.DISPLAYACTIVATIONMAPButton.Text = 'DISPLAY ACTIVATION MAP';
            app.CALCULATECONDUCTIONVELOCITYButton = uibutton(app.CONDUCTIONVELOCITYTab, 'push');
            app.CALCULATECONDUCTIONVELOCITYButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULATECONDUCTIONVELOCITYButtonPushed, true);
            app.CALCULATECONDUCTIONVELOCITYButton.Text = 'CALCULATE CONDUCTION VELOCITY';
            app.DISPLAYLOCALCVMAPButton = uibutton(app.CONDUCTIONVELOCITYTab, 'push');
            app.DISPLAYLOCALCVMAPButton.ButtonPushedFcn = createCallbackFcn(app, @DISPLAYLOCALCVMAPButtonPushed, true);
            app.DISPLAYLOCALCVMAPButton.Text = 'DISPLAY LOCAL CV MAP';
            app.SAVEDATAButton = uibutton(app.CONDUCTIONVELOCITYTab, 'push');
            app.SAVEDATAButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEDATAButtonPushed, true);
            app.SAVEDATAButton.Text = 'SAVE DATA';
            app.STEP4SELECTCVENGINEPanel = uipanel(app.CONDUCTIONVELOCITYTab);
            app.STEP4SELECTCVENGINEPanel.Title = 'STEP 4: SELECT CV ENGINE';
            app.ButtonGroup_2 = uibuttongroup(app.STEP4SELECTCVENGINEPanel);
            app.CIRCLEMETHODButton = uiradiobutton(app.ButtonGroup_2);
            app.CIRCLEMETHODButton.Text = 'CIRCLE METHOD';
            app.CIRCLEMETHODButton.Value = true;
            app.GRADIENTButton = uiradiobutton(app.ButtonGroup_2);
            app.GRADIENTButton.Text = 'GRADIENT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cadence_Conduction_Velocity

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end