classdef Cadence_Data_Conversion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        DATACONVERSIONTab             matlab.ui.container.Tab
        STEP3SELECTOUTPUTDIRECTORYTOSAVEPROCESSEDFILESPanel  matlab.ui.container.Panel
        CONVERTTOMATLABDATAFORMATButton  matlab.ui.control.Button
        Label_8                       matlab.ui.control.Label
        SELECTOUTPUTDIRECTORYButton   matlab.ui.control.Button
        EditField                     matlab.ui.control.EditField
        EditFieldLabel                matlab.ui.control.Label
        STEP1SELECTRAWDATATOCONVERTTOMATLABFORMATPanel  matlab.ui.container.Panel
        SamplingFrequencyHzEditField  matlab.ui.control.EditField
        SamplingFrequencyHzEditFieldLabel  matlab.ui.control.Label
        ListBox                       matlab.ui.control.ListBox
        REFRESHINPUTDIRECTORYButton   matlab.ui.control.Button
        SELECTINPUTDIRECTORYButton    matlab.ui.control.Button
        TextArea                      matlab.ui.control.TextArea
        TextAreaLabel                 matlab.ui.control.Label
    end


    properties (Access = public)
        console_log = strings; % keep a log of console outputs to be displayed in the text area
        select_input_directory_help = [];
        dir_name = [];
        output_dir_name = [];

    end

    methods (Access = private)


        %UPDATE_CONSOLE  Prepend a timestamped-style message to the status console.
        %   update_console(app, message)
        %   Pushes message to the top of the TextArea log and caps history at
        %   200 lines so the console never grows unbounded during long batches.
        %   Inputs:  message - char/string line to display.
        function update_console(app, message)
            existing = app.TextArea.Value;
            if numel(existing) > 200
                existing = existing(1:200);
            end
            app.TextArea.Value = [message; ''; existing];
        end

        %REFRESHDIRECTORYLISTING  Repopulate the folder ListBox from the input directory.
        %   refreshDirectoryListing(app)
        %   Lists the sub-folders of app.dir_name (each holds one recording's raw
        %   files) into app.ListBox, excluding '.' and '..'. No-ops with a console
        %   note if no input directory is set.
        function refreshDirectoryListing(app)
            if isequal(app.dir_name, 0) || isempty(app.dir_name)
                update_console(app, 'Cannot refresh: no input directory selected.');
                return;
            end
            tbl = struct2table(dir(app.dir_name));
            if isempty(tbl)
                update_console(app, 'No folders found in the selected directory.');
                return;
            end
            folders = tbl(tbl.isdir, :);
            named   = folders(~matches(folders.name, {'.', '..'}), :);
            app.ListBox.Items = convertCharsToStrings(named.name);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if ~isdeployed
                cadence_bin_path = genpath(pwd);
                addpath(cadence_bin_path);
            end
            
        end

        % Button pushed function: SELECTINPUTDIRECTORYButton
        function SELECTINPUTDIRECTORYButtonPushed(app, event)
            %SELECTINPUTDIRECTORYBUTTONPUSHED  Choose the input directory of recordings.
            %   Prompts with uigetdir, stores the result in app.dir_name, then refreshes
            %   the folder ListBox and reports the folder count. Errors are caught and
            %   logged to the console.
            try
                app.UIFigure.Visible = 'off';
                app.dir_name = uigetdir;
                app.UIFigure.Visible = 'on';
                if ~isequal(app.dir_name, 0)
                    refreshDirectoryListing(app);
                    update_console(app, strcat('Input directory selected: ', app.dir_name));
                    update_console(app, strcat(num2str(numel(app.ListBox.Items)), ' folders found.'));
                end
            catch ME
                update_console(app, strcat('ERROR - SELECT INPUT: ', ME.message));
            end
        end

        % Button pushed function: REFRESHINPUTDIRECTORYButton
        function REFRESHINPUTDIRECTORYButtonPushed(app, event)
            %REFRESHINPUTDIRECTORYBUTTONPUSHED  Re-scan the current input directory.
            %   Re-runs refreshDirectoryListing for app.dir_name (e.g. after files are
            %   added on disk) without re-prompting. Caught errors go to the console.
            try
                if ~isempty(app.dir_name)
                    refreshDirectoryListing(app);
                    update_console(app, strcat(app.dir_name, ' refreshed.'));
                end
            catch ME
                update_console(app, strcat('ERROR - REFRESH: ', ME.message));
            end
        end

        % Button pushed function: SELECTOUTPUTDIRECTORYButton
        function SELECTOUTPUTDIRECTORYButtonPushed(app, event)
            %SELECTOUTPUTDIRECTORYBUTTONPUSHED  Choose where converted .mat files are written.
            %   Prompts with uigetdir, stores app.output_dir_name and mirrors it into the
            %   EditField. Distinguishes "not selected" from "not updated" in the console;
            %   if left unset, conversion falls back to the input directory.
            try
                app.UIFigure.Visible = 'off';
                app.output_dir_name = uigetdir;
                app.UIFigure.Visible = 'on';
                if app.output_dir_name ~= 0
                    app.EditField.Value = app.output_dir_name;
                    completed_message = strcat('Output directory selected: ', app.output_dir_name);
                    update_console(app, ' ');
                    update_console(app,completed_message);
                else
                    if isempty(app.EditField.Value)
                        warning_message = 'Output directory not selected.  ';
                    else
                        warning_message = 'Output directory not updated.  ';
                    end
                    update_console(app, ' ');
                    update_console(app,warning_message);
                end

            catch ME
                error_message = strcat('ERROR - SELECTOUTPUTDIRECTORYButtonPushed(app, event) ', ME.message);
                update_console(app, ' ');
                update_console(app,error_message);
            end
        end

        % Button pushed function: CONVERTTOMATLABDATAFORMATButton
        function CONVERTTOMATLABDATAFORMATButtonPushed(app, event)
            %CONVERTTOMATLABDATAFORMATBUTTONPUSHED  Convert raw recordings to CADENCE .mat.
            %   For each selected folder, reads the raw camera data (.tif stacks via
            %   tiffreadVolume, or SciMedia .gsh via GSDconverter_BVW) into a cmos_all_data
            %   struct: CAM<n> volumes, CAM<n>_image preview thumbnails, num_files, and
            %   acqFreq (from the Sampling Frequency field, default 1000 Hz). Validates the
            %   struct with schema_gate("raw") and saves <folder>.mat (-v7.3) to the output
            %   directory. Empty folders are skipped; per-folder progress shows in a dialog
            %   and the console, and any error aborts cleanly with the dialog closed.
            try
                folder_list = app.ListBox.Value;
                if isempty(folder_list)
                    update_console(app, 'No folders selected. Please select at least one folder.');
                    return;
                end

                d = uiprogressdlg(app.UIFigure, 'Title', 'Converting data...', 'Indeterminate', 'on');

                % Resolve output directory once (not inside the loop)
                save_dir = app.EditField.Value;
                if isempty(save_dir)
                    save_dir = app.output_dir_name;
                end
                if isempty(save_dir) || isequal(save_dir, 0)
                    save_dir = app.dir_name;
                end

                % convert text field value to number once
                fs_str = app.SamplingFrequencyHzEditField.Value;
                user_fs = str2double(fs_str);
                if isnan(user_fs) || user_fs <= 0
                    user_fs = 1000;   % fall back to placeholder default
                    update_console(app, 'Sampling frequency not set or invalid; defaulting to 1000 Hz.');
                end

                sep = filesep;
                num_folders = numel(folder_list);

                for i = 1:num_folders
                    d.Message = sprintf('%d of %d — Converting %s', i, num_folders, folder_list{i});

                    input_path = fullfile(app.dir_name, folder_list{i});
                    cmos_all_data = struct();

                    % --- TIF files ---
                    tif_files = struct2cell(dir(fullfile(input_path, '*.tif')));
                    if ~isempty(tif_files)
                        %  loop + dynamic field names instead of if/if/if/if
                        %  extract thumbnail from volume (one disk read per file)
                        for j = 1:size(tif_files, 2)
                            cam_field  = sprintf('CAM%d', j);
                            img_field  = sprintf('CAM%d_image', j);
                            fpath      = fullfile(input_path, tif_files{1, j});
                            vol        = double(tiffreadVolume(fpath));
                            cmos_all_data.(cam_field) = vol;
                            % First slice as preview thumbnail — no second disk read
                            first_frame = vol(:,:,1);
                            cmos_all_data.(img_field) = round(rescale_sat(first_frame, ...
                                [min(first_frame(:)) max(first_frame(:))], [0 255]));
                        end
                        cmos_all_data.num_files = size(tif_files, 2);
                    end

                    % --- GSD files ---
                    gsh_files = struct2cell(dir(fullfile(input_path, '*.gsh')));
                    if ~isempty(gsh_files)
                        cmos_all_data.num_files = size(gsh_files, 2);
                        for j = 1:cmos_all_data.num_files
                            cmos_all_data = GSDconverter_BVW(cmos_all_data, input_path, gsh_files{1,j}, j);
                        end
                    end

                    % skip empty folders instead of aborting all remaining
                    if isempty(tif_files) && isempty(gsh_files)
                        update_console(app, sprintf('Skipping %s — no .tif or .gsh files found.', folder_list{i}));
                        continue;
                    end

                    if ~isfield(cmos_all_data, 'acqFreq')
                        cmos_all_data.acqFreq = user_fs;
                    end

                    mat_name = sprintf('%s.mat', folder_list{i});
                    file_path = fullfile(save_dir, mat_name);
                    d.Message = sprintf('Saving %s ...', mat_name);

                    [ok, msgs] = schema_gate(cmos_all_data, "raw", mat_name);
                    for k = 1:numel(msgs), update_console(app, msgs{k}); end
                    % (warn-only here — still save so you can inspect, but the log flags it)

                    save(file_path, 'cmos_all_data', '-v7.3');

                    update_console(app, sprintf('Converted %d of %d: %s', i, num_folders, folder_list{i}));
                end

                close(d);
                update_console(app, 'DATA CONVERSION COMPLETED SUCCESSFULLY.');

            catch ME
                if exist('d', 'var') && isvalid(d)
                    close(d);
                end
                update_console(app, strcat('ERROR: ', ME.message));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Cadence - Cardiac Signal Processing and Analysis Software';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DATACONVERSIONTab = uitab(app.TabGroup);
            app.DATACONVERSIONTab.Title = 'DATA CONVERSION';
            app.TextAreaLabel = uilabel(app.DATACONVERSIONTab);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.DATACONVERSIONTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Welcome to CADENCE 2026!! '; ''; 'STEP 1) Please select the appropriate input directory containing the raw data files. Also note that the software currently recognizes *.TIFF and *.GSD raw data files, which is the proprietary data format of SCIMedia high speed imaging systems (http://www.scimedia.com). Next, select one or more files for processing. You can select multiple files to process sequentially, if needed.'; ''; 'STEP 2) Select the output directory and execute the script for data conversion to .MAT data format. '; ''; 'STEP 3) Execute the script for data conversion to MATLAB format.  '};
            app.STEP1SELECTRAWDATATOCONVERTTOMATLABFORMATPanel = uipanel(app.DATACONVERSIONTab);
            app.STEP1SELECTRAWDATATOCONVERTTOMATLABFORMATPanel.Title = 'STEP 1: SELECT RAW DATA TO CONVERT TO MATLAB FORMAT';
            app.SELECTINPUTDIRECTORYButton = uibutton(app.STEP1SELECTRAWDATATOCONVERTTOMATLABFORMATPanel, 'push');
            app.SELECTINPUTDIRECTORYButton.ButtonPushedFcn = createCallbackFcn(app, @SELECTINPUTDIRECTORYButtonPushed, true);
            app.SELECTINPUTDIRECTORYButton.Text = {'SELECT INPUT DIRECTORY'; ''};
            app.REFRESHINPUTDIRECTORYButton = uibutton(app.STEP1SELECTRAWDATATOCONVERTTOMATLABFORMATPanel, 'push');
            app.REFRESHINPUTDIRECTORYButton.ButtonPushedFcn = createCallbackFcn(app, @REFRESHINPUTDIRECTORYButtonPushed, true);
            app.REFRESHINPUTDIRECTORYButton.Text = {'REFRESH INPUT DIRECTORY'; ''};
            app.ListBox = uilistbox(app.STEP1SELECTRAWDATATOCONVERTTOMATLABFORMATPanel);
            app.ListBox.Items = {};
            app.ListBox.Multiselect = 'on';
            app.ListBox.Value = {};
            app.SamplingFrequencyHzEditFieldLabel = uilabel(app.STEP1SELECTRAWDATATOCONVERTTOMATLABFORMATPanel);
            app.SamplingFrequencyHzEditFieldLabel.Text = 'Sampling Frequency (Hz)';
            app.SamplingFrequencyHzEditField = uieditfield(app.STEP1SELECTRAWDATATOCONVERTTOMATLABFORMATPanel, 'text');
            app.SamplingFrequencyHzEditField.CharacterLimits = [0 30000];
            app.SamplingFrequencyHzEditField.InputType = 'digits';
            app.SamplingFrequencyHzEditField.Value = '1000';
            app.STEP3SELECTOUTPUTDIRECTORYTOSAVEPROCESSEDFILESPanel = uipanel(app.DATACONVERSIONTab);
            app.STEP3SELECTOUTPUTDIRECTORYTOSAVEPROCESSEDFILESPanel.Title = 'STEP 2: SELECT OUTPUT DIRECTORY TO SAVE CONVERTED FILE(S) AND EXECUTE SCRIPT';
            app.EditFieldLabel = uilabel(app.STEP3SELECTOUTPUTDIRECTORYTOSAVEPROCESSEDFILESPanel);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.STEP3SELECTOUTPUTDIRECTORYTOSAVEPROCESSEDFILESPanel, 'text');
            app.EditField.Tag = 'output_dir_field';
            app.SELECTOUTPUTDIRECTORYButton = uibutton(app.STEP3SELECTOUTPUTDIRECTORYTOSAVEPROCESSEDFILESPanel, 'push');
            app.SELECTOUTPUTDIRECTORYButton.ButtonPushedFcn = createCallbackFcn(app, @SELECTOUTPUTDIRECTORYButtonPushed, true);
            app.SELECTOUTPUTDIRECTORYButton.Text = {'SELECT OUTPUT DIRECTORY'; ''};
            app.Label_8 = uilabel(app.STEP3SELECTOUTPUTDIRECTORYTOSAVEPROCESSEDFILESPanel);
            app.Label_8.Text = 'NOTE: If not selected, the converted matlab files will be saved in the same directory as the directory containing raw data files.';
            app.CONVERTTOMATLABDATAFORMATButton = uibutton(app.STEP3SELECTOUTPUTDIRECTORYTOSAVEPROCESSEDFILESPanel, 'push');
            app.CONVERTTOMATLABDATAFORMATButton.ButtonPushedFcn = createCallbackFcn(app, @CONVERTTOMATLABDATAFORMATButtonPushed, true);
            app.CONVERTTOMATLABDATAFORMATButton.Text = 'CONVERT TO MATLAB DATA FORMAT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cadence_Data_Conversion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end